classdef SimulationView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SimulatePlot      matlab.ui.control.UIAxes
        ResultsLabel      matlab.ui.control.Label
        TabGroup          matlab.ui.container.TabGroup
        PhaseportraitTab  matlab.ui.container.Tab
        TabGroup2         matlab.ui.container.TabGroup
        MMIFT4Tab         matlab.ui.container.Tab
        MMI_FT4           matlab.ui.control.UIAxes
        MMIThyroidSTab    matlab.ui.container.Tab
        MMI_TS            matlab.ui.control.UIAxes
        MMITRAbTab        matlab.ui.container.Tab
        MMI_TRAB          matlab.ui.control.UIAxes
        FT4MMITab         matlab.ui.container.Tab
        FT4_MMI           matlab.ui.control.UIAxes
        FT4ThyroidSTab    matlab.ui.container.Tab
        FT4_TS            matlab.ui.control.UIAxes
        FT4TRAbTab        matlab.ui.container.Tab
        FT4_TRAB          matlab.ui.control.UIAxes
        ThyroidSMMITab    matlab.ui.container.Tab
        TS_MMI            matlab.ui.control.UIAxes
        ThyroidSFT4Tab    matlab.ui.container.Tab
        TS_FT4            matlab.ui.control.UIAxes
        ThyroidSTRAbTab   matlab.ui.container.Tab
        TS_TRAB           matlab.ui.control.UIAxes
        TRAbMMITab        matlab.ui.container.Tab
        TRAB_MMI          matlab.ui.control.UIAxes
        TRAbFT4Tab        matlab.ui.container.Tab
        TRAB_FT4          matlab.ui.control.UIAxes
        TRAbThyroidSTab   matlab.ui.container.Tab
        TRAB_TS           matlab.ui.control.UIAxes
        DetailsTab        matlab.ui.container.Tab
        EigenResultTable  matlab.ui.control.Table
    end


    properties (Access = public)
        ISimulationResult
    end

    methods ( Static, Access = public )

        function PlotInit(app)
            res = app.ISimulationResult;
            show_simulation(app.SimulatePlot, res.T,res.X);
            
            UIAxes = [app.MMI_FT4 app.MMI_TS app.MMI_TRAB app.FT4_MMI app.FT4_TS app.FT4_TRAB  app.TS_MMI  app.TS_FT4 app.TS_TRAB app.TRAB_MMI app.TRAB_FT4 app.TRAB_TS];
            show_stability(UIAxes, res.X);
            
%             chArray = num2str(res.eigen);
%             sArray = convertCharsToStrings(chArray);
%             s = strjoin(sArray);
%             app.eigResult.Text = s;
            tdata = table(res.eigen);
%             uit = uitable(app.EigenResultTable,'Data',tdata);
            app.EigenResultTable.Data = tdata;

%             app.eigResult.Text = mat2str(res.eigen);
            
            xlabel(app.FT4_TS,'FT4');
            ylabel(app.FT4_TS,'Thyroid size');
        end
    end


    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SimulatePlot = uiaxes(app.UIFigure);
            title(app.SimulatePlot, 'Title')
            xlabel(app.SimulatePlot, 'X')
            ylabel(app.SimulatePlot, 'Y')
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PhaseportraitTab = uitab(app.TabGroup);
            app.PhaseportraitTab.Title = 'Phase portrait';
            app.TabGroup2 = uitabgroup(app.PhaseportraitTab);
            app.MMIFT4Tab = uitab(app.TabGroup2);
            app.MMIFT4Tab.Title = 'MMI-FT4';
            app.MMI_FT4 = uiaxes(app.MMIFT4Tab);
            title(app.MMI_FT4, 'Title')
            xlabel(app.MMI_FT4, 'X')
            ylabel(app.MMI_FT4, 'Y')
            app.MMIThyroidSTab = uitab(app.TabGroup2);
            app.MMIThyroidSTab.Title = 'MMI-Thyroid S';
            app.MMI_TS = uiaxes(app.MMIThyroidSTab);
            title(app.MMI_TS, 'Title')
            xlabel(app.MMI_TS, 'X')
            ylabel(app.MMI_TS, 'Y')
            app.MMITRAbTab = uitab(app.TabGroup2);
            app.MMITRAbTab.Title = 'MMI-TRAb';
            app.MMI_TRAB = uiaxes(app.MMITRAbTab);
            title(app.MMI_TRAB, 'Title')
            xlabel(app.MMI_TRAB, 'X')
            ylabel(app.MMI_TRAB, 'Y')
            app.FT4MMITab = uitab(app.TabGroup2);
            app.FT4MMITab.Title = 'FT4-MMI';
            app.FT4_MMI = uiaxes(app.FT4MMITab);
            title(app.FT4_MMI, 'Title')
            xlabel(app.FT4_MMI, 'X')
            ylabel(app.FT4_MMI, 'Y')
            app.FT4ThyroidSTab = uitab(app.TabGroup2);
            app.FT4ThyroidSTab.Title = 'FT4-Thyroid S';
            app.FT4_TS = uiaxes(app.FT4ThyroidSTab);
            title(app.FT4_TS, 'Title')
            xlabel(app.FT4_TS, 'X')
            ylabel(app.FT4_TS, 'Y')
            app.FT4TRAbTab = uitab(app.TabGroup2);
            app.FT4TRAbTab.Title = 'FT4-TRAb';
            app.FT4_TRAB = uiaxes(app.FT4TRAbTab);
            title(app.FT4_TRAB, 'Title')
            xlabel(app.FT4_TRAB, 'X')
            ylabel(app.FT4_TRAB, 'Y')
            app.ThyroidSMMITab = uitab(app.TabGroup2);
            app.ThyroidSMMITab.Title = 'Thyroid S - MMI';
            app.TS_MMI = uiaxes(app.ThyroidSMMITab);
            title(app.TS_MMI, 'Title')
            xlabel(app.TS_MMI, 'X')
            ylabel(app.TS_MMI, 'Y')
            app.ThyroidSFT4Tab = uitab(app.TabGroup2);
            app.ThyroidSFT4Tab.Title = 'Thyroid S - FT4';
            app.TS_FT4 = uiaxes(app.ThyroidSFT4Tab);
            title(app.TS_FT4, 'Title')
            xlabel(app.TS_FT4, 'X')
            ylabel(app.TS_FT4, 'Y')
            app.ThyroidSTRAbTab = uitab(app.TabGroup2);
            app.ThyroidSTRAbTab.Title = 'Thyroid S - TRAb';
            app.TS_TRAB = uiaxes(app.ThyroidSTRAbTab);
            title(app.TS_TRAB, 'Title')
            xlabel(app.TS_TRAB, 'X')
            ylabel(app.TS_TRAB, 'Y')
            app.TRAbMMITab = uitab(app.TabGroup2);
            app.TRAbMMITab.Title = 'TRAb - MMI';
            app.TRAB_MMI = uiaxes(app.TRAbMMITab);
            title(app.TRAB_MMI, 'Title')
            xlabel(app.TRAB_MMI, 'X')
            ylabel(app.TRAB_MMI, 'Y')
            app.TRAbFT4Tab = uitab(app.TabGroup2);
            app.TRAbFT4Tab.Title = 'TRAb-FT4';
            app.TRAB_FT4 = uiaxes(app.TRAbFT4Tab);
            title(app.TRAB_FT4, 'Title')
            xlabel(app.TRAB_FT4, 'X')
            ylabel(app.TRAB_FT4, 'Y')
            app.TRAbThyroidSTab = uitab(app.TabGroup2);
            app.TRAbThyroidSTab.Title = 'TRAb-Thyroid S';
            app.TRAB_TS = uiaxes(app.TRAbThyroidSTab);
            title(app.TRAB_TS, 'Title')
            xlabel(app.TRAB_TS, 'X')
            ylabel(app.TRAB_TS, 'Y')
            app.DetailsTab = uitab(app.TabGroup);
            app.DetailsTab.Title = 'Details';
            app.EigenResultTable = uitable(app.DetailsTab);
            app.EigenResultTable.ColumnName = {'Eigen Values'};
            app.EigenResultTable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimulationView

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end