classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ChoosePatientDropDownLabel  matlab.ui.control.Label
        ChoosePatientDropDown       matlab.ui.control.DropDown
        UITable                     matlab.ui.control.Table
        EditparameterLabel          matlab.ui.control.Label
        DoseSliderLabel             matlab.ui.control.Label
        DoseSlider                  matlab.ui.control.Slider
        AdvanceddoseButton          matlab.ui.control.Button
        MMItreatmentLabel           matlab.ui.control.Label
        InitialvaluesLabel          matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        MMISliderLabel              matlab.ui.control.Label
        MMISlider                   matlab.ui.control.Slider
        DaysSliderLabel             matlab.ui.control.Label
        DaysSlider                  matlab.ui.control.Slider
        PhaseportraitButton         matlab.ui.control.Button
        EigenvaluesButton           matlab.ui.control.Button
        FT4SliderLabel              matlab.ui.control.Label
        FT4Slider                   matlab.ui.control.Slider
        ThyroidsizeSliderLabel      matlab.ui.control.Label
        ThyroidsizeSlider           matlab.ui.control.Slider
        TRAbSliderLabel             matlab.ui.control.Label
        TRAbSlider                  matlab.ui.control.Slider
        SimulateButton              matlab.ui.control.Button
    end


    properties (Access = public)
        Data, % Description data to plot
        mapPatient,
        StartData,
        currentPatient
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mStartData = IStartData;
            
            mStartData.PatientList = GeneratePatientList();
            mStartData.InitialValues = [ 0,36,30,25 ];
            mStartData.Doses = GenerateDoses();
            
            tableData = mStartData.PatientList(1).getTable();
            app.UITable.Data = tableData;
            
            map = containers.Map;
            [a, b] = size(mStartData.PatientList);
            
            patientLabels = string(b);
            for i = 1: b
                newLabel = mStartData.PatientList(i).label;
                map(newLabel) = i;
                patientLabels(i) = newLabel;
            end
             
%             export variables
            app.mapPatient = map;
            app.ChoosePatientDropDown.Items = patientLabels;
            app.StartData = mStartData;
            
        end

        % Button pushed function: PhaseportraitButton
        function PhasePortraitClick(app, event)
            
        end

        % Button pushed function: SimulateButton
        function SimulateBtnClick(app, event)
            app.SimulateButton.Text = 'WAIT';
            [T, X, eigen] = Run_plik_glowny();
            app.Data = [T X];
            app.SimulateButton.Text = 'Simulate';
            
            showApp = phase_portrait;
            showApp.T = T;
            showApp.X = X;
            showApp.eigen = eigen;
            
            showApp.PlotInit(showApp);
        end

        % Value changed function: ChoosePatientDropDown
        function ChoosePatientDropDownValueChanged(app, event)
            currentLabel = app.ChoosePatientDropDown.Value;
            pIndex = app.mapPatient(currentLabel);
            
            tableData = app.StartData.PatientList(pIndex).getTable();
            app.UITable.Data = tableData;
            
            app.currentPatient = app.StartData.PatientList(pIndex); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChoosePatientDropDownLabel = uilabel(app.UIFigure);
            app.ChoosePatientDropDownLabel.Text = 'Choose Patient';
            app.ChoosePatientDropDown = uidropdown(app.UIFigure);
            app.ChoosePatientDropDown.Items = {};
            app.ChoosePatientDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePatientDropDownValueChanged, true);
            app.ChoosePatientDropDown.Value = {};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.EditparameterLabel = uilabel(app.UIFigure);
            app.EditparameterLabel.Text = 'Edit parameter';
            app.DoseSliderLabel = uilabel(app.UIFigure);
            app.DoseSliderLabel.Text = 'Dose';
            app.DoseSlider = uislider(app.UIFigure);
            app.DoseSlider.Value = 30;
            app.AdvanceddoseButton = uibutton(app.UIFigure, 'push');
            app.AdvanceddoseButton.Text = 'Advanced dose';
            app.MMItreatmentLabel = uilabel(app.UIFigure);
            app.MMItreatmentLabel.Text = 'MMI treatment';
            app.InitialvaluesLabel = uilabel(app.UIFigure);
            app.InitialvaluesLabel.Text = 'Initial values';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.MMISliderLabel = uilabel(app.UIFigure);
            app.MMISliderLabel.Text = 'MMI';
            app.MMISlider = uislider(app.UIFigure);
            app.DaysSliderLabel = uilabel(app.UIFigure);
            app.DaysSliderLabel.Text = 'Days';
            app.DaysSlider = uislider(app.UIFigure);
            app.DaysSlider.Limits = [0 90];
            app.DaysSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.DaysSlider.Value = 25;
            app.PhaseportraitButton = uibutton(app.UIFigure, 'push');
            app.PhaseportraitButton.ButtonPushedFcn = createCallbackFcn(app, @PhasePortraitClick, true);
            app.PhaseportraitButton.Text = 'Phase portrait';
            app.EigenvaluesButton = uibutton(app.UIFigure, 'push');
            app.EigenvaluesButton.Text = 'Eigen values';
            app.FT4SliderLabel = uilabel(app.UIFigure);
            app.FT4SliderLabel.Text = 'FT4';
            app.FT4Slider = uislider(app.UIFigure);
            app.ThyroidsizeSliderLabel = uilabel(app.UIFigure);
            app.ThyroidsizeSliderLabel.Text = {'Thyroid'; 'size'};
            app.ThyroidsizeSlider = uislider(app.UIFigure);
            app.TRAbSliderLabel = uilabel(app.UIFigure);
            app.TRAbSliderLabel.Text = 'TRAb';
            app.TRAbSlider = uislider(app.UIFigure);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateBtnClick, true);
            app.SimulateButton.Text = 'Simulate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end